classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SeleccinTab                     matlab.ui.container.Tab
        CargararchivoButton             matlab.ui.control.Button
        ReproduciraudiooriginalButton   matlab.ui.control.Button
        Lamp_2                          matlab.ui.control.Lamp
        Label                           matlab.ui.control.Label
        phaseroflangerCheckBox          matlab.ui.control.CheckBox
        ecoCheckBox                     matlab.ui.control.CheckBox
        reverberacinCheckBox            matlab.ui.control.CheckBox
        TipodefiltroDropDown            matlab.ui.control.DropDown
        TipodefiltroDropDownLabel       matlab.ui.control.Label
        FrecuenciaCentralEditFieldLabel  matlab.ui.control.Label
        FrecuenciaCentralEditField      matlab.ui.control.NumericEditField
        FactordecalidadEditFieldLabel   matlab.ui.control.Label
        FactordecalidadEditField        matlab.ui.control.NumericEditField
        FrecuenciadecortebajaEditFieldLabel  matlab.ui.control.Label
        FrecuenciadecortebajaEditField  matlab.ui.control.NumericEditField
        FrecuenciadecortealtaEditFieldLabel  matlab.ui.control.Label
        FrecuenciadecortealtaEditField  matlab.ui.control.NumericEditField
        distorsinCheckBox               matlab.ui.control.CheckBox
        vibratoCheckBox                 matlab.ui.control.CheckBox
        ReproduciraudiofiltradoButton   matlab.ui.control.Button
        AplicarfiltradoButton           matlab.ui.control.Button
        PlotButton                      matlab.ui.control.Button
        GainSlider                      matlab.ui.control.Slider
        GainSliderLabel                 matlab.ui.control.Label
        Lamp                            matlab.ui.control.Lamp
        ExportarButton                  matlab.ui.control.Button
        SalvaraudiofiltradoButton       matlab.ui.control.Button
        NombredearchivoEditField        matlab.ui.control.EditField
        NombredearchivoEditFieldLabel   matlab.ui.control.Label
        SeleccindefiltroLabel           matlab.ui.control.Label
        EfectosLabel                    matlab.ui.control.Label
        ParmetrosdelfiltroLabel         matlab.ui.control.Label
        GraficosTab                     matlab.ui.container.Tab
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    methods (Access = private)

        % Button pushed function: CargararchivoButton
        function Graficar(app, event)
            
            global file;
            
            file = uigetfile({'*.wav'});   % selecciona un archivo con extensiones específicas
            
            if  file == 0
                app.Label.Text = 'Archivo no seleccionado';
            else
                app.Label.Text = file;
            end
            
        end

        % Value changed function: TipodefiltroDropDown
        function TipodefiltroDropDownValueChanged(app, event)
            value = app.TipodefiltroDropDown.Value;
            if value =='FIR'
                %progra filtro FIR
                    
            else 
         %progra filtro IIR
            end
        end

        % Button pushed function: ReproduciraudiooriginalButton
        function play_original(app, event)
            
            global file; 
            global x; 
            global Fs;
            
            [x, Fs] = audioread(file);  % obtiene los datos de un archivo *.wav
            soundsc(x, Fs); % reproduce el audio original seleccionado
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            
            global x;
            
            t = linspace(0,1000000000);
            plot(app.UIAxes2,x);
            
        end

        % Button pushed function: ExportarButton
        function Exportar(app, event)
            
            global x;                               % aquí va la variable del audio modificado
            global Fs;
            out_filename = app.NombredearchivoEditField.Value; % guarda nombre ingresado en la campo de edición
            out_filename = strcat(out_filename, ".wav")
            [file, path] = uiputfile(out_filename); % obtiene la dirección y el nombre para guardar el archivo
            
            % en caso de que se cancele "guardar archivo"
            if file == 0   
                return;
            end
            
            fullFileName = fullfile(path, file);    % construye la ubicación del archivo
            audiowrite(fullFileName, x, Fs)         % guarda el archivo en un formato *.wav
            
        end

        % Button pushed function: ReproduciraudiofiltradoButton
        function play_modified(app, event)
        
            global x_out;   % variable del audio modificado
            global Fs;
          
            soundsc(x_out, Fs); % reproduce el audio original seleccionado
            
        end

        % Value changed function: ecoCheckBox
        function toggle_eco_effect(app, event)
            
            global x;
            global x_out;
            
            value = app.ecoCheckBox.Value;
            
            N = length(x)
            D = round(N/6);
            alpha = 0.7;
            
            if value
                for n = 1:N
                    k = n-D;
                    if k < 1
                        x_out(n) = x(n);
                    else
                        x_out(n) = x(n) + x(k) + (1-alpha)*x(k) + alpha*x_out(k);
                    end
                end
            else
                % por ver que colocar aquí
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SeleccinTab = uitab(app.TabGroup);
            app.SeleccinTab.Title = 'Selección';
            app.CargararchivoButton = uibutton(app.SeleccinTab, 'push');
            app.CargararchivoButton.ButtonPushedFcn = createCallbackFcn(app, @Graficar, true);
            app.CargararchivoButton.Text = 'Cargar archivo';
            app.ReproduciraudiooriginalButton = uibutton(app.SeleccinTab, 'push');
            app.ReproduciraudiooriginalButton.ButtonPushedFcn = createCallbackFcn(app, @play_original, true);
            app.ReproduciraudiooriginalButton.Text = 'Reproducir audio original';
            app.Lamp_2 = uilamp(app.SeleccinTab);
            app.Label = uilabel(app.SeleccinTab);
            app.Label.Text = '';
            app.phaseroflangerCheckBox = uicheckbox(app.SeleccinTab);
            app.phaseroflangerCheckBox.Text = 'phaser o flanger';
            app.ecoCheckBox = uicheckbox(app.SeleccinTab);
            app.ecoCheckBox.ValueChangedFcn = createCallbackFcn(app, @toggle_eco_effect, true);
            app.ecoCheckBox.Text = 'eco';
            app.reverberacinCheckBox = uicheckbox(app.SeleccinTab);
            app.reverberacinCheckBox.Text = 'reverberación';
            app.TipodefiltroDropDown = uidropdown(app.SeleccinTab);
            app.TipodefiltroDropDown.Items = {'FIR', 'IIR'};
            app.TipodefiltroDropDown.ValueChangedFcn = createCallbackFcn(app, @TipodefiltroDropDownValueChanged, true);
            app.TipodefiltroDropDown.Value = 'FIR';
            app.TipodefiltroDropDownLabel = uilabel(app.SeleccinTab);
            app.TipodefiltroDropDownLabel.Text = 'Tipo de filtro';
            app.FrecuenciaCentralEditFieldLabel = uilabel(app.SeleccinTab);
            app.FrecuenciaCentralEditFieldLabel.Text = 'Frecuencia Central';
            app.FrecuenciaCentralEditField = uieditfield(app.SeleccinTab, 'numeric');
            app.FactordecalidadEditFieldLabel = uilabel(app.SeleccinTab);
            app.FactordecalidadEditFieldLabel.Text = 'Factor de calidad';
            app.FactordecalidadEditField = uieditfield(app.SeleccinTab, 'numeric');
            app.FrecuenciadecortebajaEditFieldLabel = uilabel(app.SeleccinTab);
            app.FrecuenciadecortebajaEditFieldLabel.Text = 'Frecuencia de corte baja';
            app.FrecuenciadecortebajaEditField = uieditfield(app.SeleccinTab, 'numeric');
            app.FrecuenciadecortealtaEditFieldLabel = uilabel(app.SeleccinTab);
            app.FrecuenciadecortealtaEditFieldLabel.Text = 'Frecuencia de corte alta';
            app.FrecuenciadecortealtaEditField = uieditfield(app.SeleccinTab, 'numeric');
            app.distorsinCheckBox = uicheckbox(app.SeleccinTab);
            app.distorsinCheckBox.Text = 'distorsión';
            app.vibratoCheckBox = uicheckbox(app.SeleccinTab);
            app.vibratoCheckBox.Text = 'vibrato';
            app.ReproduciraudiofiltradoButton = uibutton(app.SeleccinTab, 'push');
            app.ReproduciraudiofiltradoButton.ButtonPushedFcn = createCallbackFcn(app, @play_modified, true);
            app.ReproduciraudiofiltradoButton.Text = 'Reproducir audio filtrado';
            app.AplicarfiltradoButton = uibutton(app.SeleccinTab, 'push');
            app.AplicarfiltradoButton.Text = 'Aplicar filtrado';
            app.PlotButton = uibutton(app.SeleccinTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.GainSlider = uislider(app.SeleccinTab);
            app.GainSlider.Limits = [0 1];
            app.GainSliderLabel = uilabel(app.SeleccinTab);
            app.GainSliderLabel.Text = 'Gain';
            app.Lamp = uilamp(app.SeleccinTab);
            app.ExportarButton = uibutton(app.SeleccinTab, 'push');
            app.ExportarButton.ButtonPushedFcn = createCallbackFcn(app, @Exportar, true);
            app.ExportarButton.Text = 'Exportar';
            app.SalvaraudiofiltradoButton = uibutton(app.SeleccinTab, 'push');
            app.SalvaraudiofiltradoButton.Text = 'Salvar audio filtrado';
            app.NombredearchivoEditField = uieditfield(app.SeleccinTab, 'text');
            app.NombredearchivoEditFieldLabel = uilabel(app.SeleccinTab);
            app.NombredearchivoEditFieldLabel.Text = 'Nombre de archivo';
            app.SeleccindefiltroLabel = uilabel(app.SeleccinTab);
            app.SeleccindefiltroLabel.Text = 'Selección de filtro';
            app.EfectosLabel = uilabel(app.SeleccinTab);
            app.EfectosLabel.Text = 'Efectos';
            app.ParmetrosdelfiltroLabel = uilabel(app.SeleccinTab);
            app.ParmetrosdelfiltroLabel.Text = 'Parámetros del filtro';
            app.GraficosTab = uitab(app.TabGroup);
            app.GraficosTab.Title = 'Graficos';
            app.UIAxes2 = uiaxes(app.GraficosTab);
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.GraficosTab);
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes = uiaxes(app.GraficosTab);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
        end
    end

    methods (Access = public)

        % Construct app
        function app = App

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end